classdef SPApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PositionandOrientationPanel  matlab.ui.container.Panel
        YawEditFieldLabel            matlab.ui.control.Label
        YawEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel          matlab.ui.control.Label
        PitchEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel           matlab.ui.control.Label
        RollEditField                matlab.ui.control.NumericEditField
        XTranslationEditFieldLabel   matlab.ui.control.Label
        XTranslationEditField        matlab.ui.control.NumericEditField
        YTranslationEditFieldLabel   matlab.ui.control.Label
        YTranslationEditField        matlab.ui.control.NumericEditField
        ZTranslationEditFieldLabel   matlab.ui.control.Label
        ZTranslationEditField        matlab.ui.control.NumericEditField
        MoveModelOnlyButton          matlab.ui.control.Button
        MoveButton                   matlab.ui.control.Button
        MovePeriodEditFieldLabel     matlab.ui.control.Label
        MovePeriodEditField          matlab.ui.control.NumericEditField
        ConfigurationCommandsPanel   matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
    end


    properties (Access = private)
        stewie = []; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stewie = StewartPlatform(152, 18.92, 103, 28.07, 73, 165);
            app.stewie.connect('COM4');
            % Get platform to initial position
            app.stewie.reset;
            cal = 600;
            app.stewie.set_actuator(1, cal, -cal, cal, -cal, cal, -cal);
            pause(1.2);
            app.stewie.reset;
            app.stewie.plot_platform;
        end

        % Button pushed function: MoveModelOnlyButton
        function MoveModelOnlyButtonPushed(app, event)
            yaw = app.YawEditField.Value;
            pitch = app.PitchEditField.Value;
            roll = app.RollEditField.Value;
            xTrans = app.XTranslationEditField.Value;
            yTrans = app.YTranslationEditField.Value;
            zTrans = app.ZTranslationEditField.Value;
            app.stewie.set_position(roll, pitch, yaw, xTrans, yTrans, zTrans);
            app.stewie.plot_platform;
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            yaw = app.YawEditField.Value;
            pitch = app.PitchEditField.Value;
            roll = app.RollEditField.Value;
            xTrans = app.XTranslationEditField.Value;
            yTrans = app.YTranslationEditField.Value;
            zTrans = app.ZTranslationEditField.Value;
            app.stewie.set_position(roll, pitch, yaw, xTrans, yTrans, zTrans);
            app.stewie.plot_platform;
            movPeriod = app.MovePeriodEditField.Value;
            app.stewie.move(movPeriod);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.stewie.reset;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.stewie.calibrate;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PositionandOrientationPanel = uipanel(app.UIFigure);
            app.PositionandOrientationPanel.Title = 'Position and Orientation';
            app.YawEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.YawEditField.Limits = [-180 180];
            app.PitchEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.PitchEditField.Limits = [-180 180];
            app.RollEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.RollEditField.Limits = [-180 180];
            app.XTranslationEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.XTranslationEditFieldLabel.Text = 'X Translation';
            app.XTranslationEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.YTranslationEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.YTranslationEditFieldLabel.Text = 'Y Translation';
            app.YTranslationEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.ZTranslationEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.ZTranslationEditFieldLabel.Text = 'Z Translation';
            app.ZTranslationEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.ZTranslationEditField.Value = 140;
            app.MoveModelOnlyButton = uibutton(app.PositionandOrientationPanel, 'push');
            app.MoveModelOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @MoveModelOnlyButtonPushed, true);
            app.MoveModelOnlyButton.Text = 'Move Model Only';
            app.MoveButton = uibutton(app.PositionandOrientationPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.MovePeriodEditFieldLabel = uilabel(app.PositionandOrientationPanel);
            app.MovePeriodEditFieldLabel.Text = 'Move Period';
            app.MovePeriodEditField = uieditfield(app.PositionandOrientationPanel, 'numeric');
            app.MovePeriodEditField.Limits = [0.05 Inf];
            app.MovePeriodEditField.Value = 0.5;
            app.ConfigurationCommandsPanel = uipanel(app.UIFigure);
            app.ConfigurationCommandsPanel.Title = 'Configuration Commands';
            app.ResetButton = uibutton(app.ConfigurationCommandsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CalibrateButton = uibutton(app.ConfigurationCommandsPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SPApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end